classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IRImageDataExtractorUIFigure   matlab.ui.Figure
        LowTemperatureEditField        matlab.ui.control.EditField
        HighTemperatureEditField       matlab.ui.control.EditField
        HighTemperatureEditFieldLabel  matlab.ui.control.Label
        LowTemperatureEditFieldLabel   matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        ExporttoCSVButton              matlab.ui.control.Button
        SelectCornersButton            matlab.ui.control.Button
        LoadIRImageButton              matlab.ui.control.Button
        FileIdentifierEditField        matlab.ui.control.EditField
        FileIdentifierEditFieldLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
        filename
        fileid
        Avg_T
        Sd_T
        T_matrix
        xi2
        yi2
        h
        sectionid
        X
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIRImageButton
        function LoadIRImageButtonPushed(app, event)
            cla(app.UIAxes)
            app.IRImageDataExtractorUIFigure.HandleVisibility = 'on';
            app.IRImageDataExtractorUIFigure.Visible = 'off';
            [file, path] = uigetfile('*.jpg');
            app.fileid = file(1:end-4);
            app.filename = fullfile(path,file);
            app.IRImageDataExtractorUIFigure.Visible = 'on';
            a = imread(app.filename);
            imshow(a,'Parent',app.UIAxes);
            title(app.UIAxes, strcat('Image Preview','-',app.fileid));
            imgH  = imcrop(a,[568, 10, 62, 34]);
            imgL  = imcrop(a,[568, 440, 62, 34]);
            xH = ocr(imgH, 'CharacterSet', '0123456789.-');
            HighT = cell2mat(xH.Words);
            xH = ocr(imgL, 'CharacterSet', '0123456789.-');
            LowT = cell2mat(xH.Words);
            app.HighTemperatureEditField.Value = HighT;
            app.LowTemperatureEditField.Value = LowT;
            hold on;
%             app.EditField.Value = 'Message: Start clicking corners required, Double click while the selecting the last corner';
%             app.IRImageDataExtractorUIFigure.HandleVisibility = 'on';
%             set(0, 'CurrentFigure', app.IRImageDataExtractorUIFigure)
%             set(app.IRImageDataExtractorUIFigure,'CurrentAxes',app.UIAxes)
%             [app.xi2,app.yi2,~] = impixel(a);
%             if (length(app.xi2)<3)
%                 app.EditField.Value = 'Error: No. of corners value should be greater than 2.';
%                 return
%             end
%             x(1,:) = app.xi2';
%             x(2,:) = app.yi2';
%             x(1, length(app.xi2)+1) = x(1,1);
%             x(2, length(app.xi2)+1) = x(2,1);
%             app.X = x;
%             app.h = app.UIAxes;
%             set(0, 'CurrentFigure', app.IRImageDataExtractorUIFigure)
%             set(app.IRImageDataExtractorUIFigure,'CurrentAxes',app.UIAxes)
%             for i = 1:length(x)-1
%                 plot(app.UIAxes, [x(1,i) x(1,i+1)], [x(2,i), x(2,i+1)], 'go-');
%                 hold on;
%             end
%             hold off;
%             app.IRImageDataExtractorUIFigure.Visible = 'on';
%             app.IRImageDataExtractorUIFigure.HandleVisibility = 'off';
%             %app.EditField.Value = "HIIIIII";
%             [app.Avg_T, app.Sd_T, app.T_matrix] = get_T_app(app.X, app.filename);
%             c = ["Average Temperature:",num2str(app.Avg_T), " and Standard Deviation:", num2str(app.Sd_T)];
%             app.EditField.Value = join(c);
        end

        % Button pushed function: SelectCornersButton
        function SelectCornersButtonPushed(app, event)
            cla(app.UIAxes)
            app.EditField.Value = 'Message: Start clicking corners required, Double click while the selecting the last corner';
            app.IRImageDataExtractorUIFigure.HandleVisibility = 'on';
            a = imread(app.filename);
            imshow(a, 'parent', app.UIAxes);
            hold on;
            app.IRImageDataExtractorUIFigure.HandleVisibility = 'on';
            set(0, 'CurrentFigure', app.IRImageDataExtractorUIFigure)
            set(app.IRImageDataExtractorUIFigure,'CurrentAxes',app.UIAxes)
            [app.xi2,app.yi2,~] = impixel(a);
            
            %app.IRImageDataExtractorUIFigure.HandleVisibility = 'off'; 
            if (length(app.xi2)<3)
                app.EditField.Value = 'Error: No. of corners value should be greater than 2.';
                return
            end
            x(1,:) = app.xi2';
            x(2,:) = app.yi2';
            x(1, length(app.xi2)+1) = x(1,1);
            x(2, length(app.xi2)+1) = x(2,1);
            app.X = x;
            app.h = app.UIAxes;
            set(0, 'CurrentFigure', app.IRImageDataExtractorUIFigure)
            set(app.IRImageDataExtractorUIFigure,'CurrentAxes',app.UIAxes)
            for i = 1:length(x)-1
                plot(app.UIAxes, [x(1,i) x(1,i+1)], [x(2,i), x(2,i+1)], 'go-');
                hold on;
            end
            hold off;
            app.IRImageDataExtractorUIFigure.HandleVisibility = 'off';
            Thigh = str2double(app.HighTemperatureEditField.Value);
            TLow = str2double(app.LowTemperatureEditField.Value);
            
            [app.Avg_T, app.Sd_T, app.T_matrix] = get_T_app(app.X, app.filename, Thigh, TLow);
            c = ["Average Temperature:",num2str(app.Avg_T), " and Standard Deviation:", num2str(app.Sd_T)];
            app.EditField.Value = join(c);
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            app.IRImageDataExtractorUIFigure.Visible = 'off';
            path = uigetdir;
            app.IRImageDataExtractorUIFigure.Visible = 'on';
            F = fullfile(path,app.fileid);
            if app.FileIdentifierEditField.Value == "Nil"
                app.EditField.Value = 'Enter File Identifier';
                return
            else
                app.sectionid = app.FileIdentifierEditField.Value;
            end
            
            csvwrite(strcat(F,app.sectionid,".csv") , app.T_matrix );
            c = ['Data Exported to ', F, app.sectionid,'.csv'];
            app.EditField.Value = join(c);
        end

        % Value changing function: FileIdentifierEditField
        function FileIdentifierEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = 'Enter any identication tag for exporting csv filename';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IRImageDataExtractorUIFigure = uifigure('Visible', 'off');
            app.IRImageDataExtractorUIFigure.Name = 'IR Image Data Extractor';
            app.UIAxes = uiaxes(app.IRImageDataExtractorUIFigure);
            title(app.UIAxes, 'Image Preview')
            zlabel(app.UIAxes, 'Z')
            app.FileIdentifierEditFieldLabel = uilabel(app.IRImageDataExtractorUIFigure);
            app.FileIdentifierEditFieldLabel.Text = 'File Identifier';
            app.FileIdentifierEditField = uieditfield(app.IRImageDataExtractorUIFigure, 'text');
            app.FileIdentifierEditField.ValueChangingFcn = createCallbackFcn(app, @FileIdentifierEditFieldValueChanging, true);
            app.FileIdentifierEditField.Value = 'Nil';
            app.LoadIRImageButton = uibutton(app.IRImageDataExtractorUIFigure, 'push');
            app.LoadIRImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIRImageButtonPushed, true);
            app.LoadIRImageButton.Text = 'Load IR Image';
            app.SelectCornersButton = uibutton(app.IRImageDataExtractorUIFigure, 'push');
            app.SelectCornersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCornersButtonPushed, true);
            app.SelectCornersButton.Text = 'Select Corners';
            app.ExporttoCSVButton = uibutton(app.IRImageDataExtractorUIFigure, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Text = 'Export to CSV';
            app.EditField = uieditfield(app.IRImageDataExtractorUIFigure, 'text');
            app.LowTemperatureEditFieldLabel = uilabel(app.IRImageDataExtractorUIFigure);
            app.LowTemperatureEditFieldLabel.Text = 'Low Temperature';
            app.HighTemperatureEditFieldLabel = uilabel(app.IRImageDataExtractorUIFigure);
            app.HighTemperatureEditFieldLabel.Text = 'High Temperature';
            app.HighTemperatureEditField = uieditfield(app.IRImageDataExtractorUIFigure, 'text');
            app.LowTemperatureEditField = uieditfield(app.IRImageDataExtractorUIFigure, 'text');
            % Show the figure after all components are created
            app.IRImageDataExtractorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IRImageDataExtractorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IRImageDataExtractorUIFigure)
        end
    end
end